classdef Kick_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        KickDesignLabel                matlab.ui.control.Label
        Actuator1Label                 matlab.ui.control.Label
        Actuator2Label                 matlab.ui.control.Label
        WallDisplacementmmLabel        matlab.ui.control.Label
        WallDisplacementmmEditField    matlab.ui.control.NumericEditField
        Actuator1sEditFieldLabel       matlab.ui.control.Label
        Actuator1sEditField            matlab.ui.control.NumericEditField
        PauseatthepicksLabel           matlab.ui.control.Label
        PauseatthepicksEditField       matlab.ui.control.NumericEditField
        WallDisplacementmmLabel_2      matlab.ui.control.Label
        WallDisplacementmmEditField_2  matlab.ui.control.NumericEditField
        Actuator2sEditFieldLabel       matlab.ui.control.Label
        Actuator2sEditField            matlab.ui.control.NumericEditField
        PauseatthepicksLabel_2         matlab.ui.control.Label
        PauseatthepicksEditField_2     matlab.ui.control.NumericEditField
        StartwithActuatorLabel         matlab.ui.control.Label
        StartwithActuatorDropDown      matlab.ui.control.DropDown
        NoofKicksEditField_2Label      matlab.ui.control.Label
        NoofKicksEditField_2           matlab.ui.control.NumericEditField
        NoofKicksEditField_3Label      matlab.ui.control.Label
        NoofKicksEditField_3           matlab.ui.control.NumericEditField
        DelayBetweenKickssLabel        matlab.ui.control.Label
        DelayBetweenKickssEditField    matlab.ui.control.NumericEditField
        FetalKickSimulatorLabel        matlab.ui.control.Label
        KickingActionLabel             matlab.ui.control.Label
        StartKickingButton             matlab.ui.control.Button
        DelayBetweenActuatorssLabel    matlab.ui.control.Label
        DelayBetweenActuatorssEditField  matlab.ui.control.NumericEditField
        FeedbackLabel                  matlab.ui.control.Label
        DelayBetweenKickssLabel_2      matlab.ui.control.Label
        DelayBetweenKickssEditField_2  matlab.ui.control.NumericEditField
        KickDurationLabel              matlab.ui.control.Label
        StatusEditFieldLabel           matlab.ui.control.Label
        StatusEditField                matlab.ui.control.EditField
        KickModeDropDownLabel          matlab.ui.control.Label
        KickModeDropDown               matlab.ui.control.DropDown
        WarningEditFieldLabel          matlab.ui.control.Label
        WarningEditField               matlab.ui.control.EditField
        ConnecttheArduinoButton        matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        DataSamplingPeriodsEditFieldLabel  matlab.ui.control.Label
        DataSamplingPeriodsEditField   matlab.ui.control.NumericEditField
        RealtimePlottingCheckBox       matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; % Can't use clear here: Because in that case comment bix does not work
            %
            % Setting up the Arduino connection by calling the relavent function
            ConnecttheArduinoButtonPushed(app);
            %
        end

        % Button pushed function: StartKickingButton
        function StartKickingButtonPushed(app, event)
            % Decleration of the golbal variables
            global a1 s act1 act2 gap_between_act_wall no_load_speed full_load_speed m_act1 m_act2 check_if_kicking_on check_if_arduino_connected ...
                sensor1 sensor2 data_number sampling_frequency
            %
            try % In case some error is generated in this section, which can happen due to power or connection loss, the program execution goes to the catch statement
                %
                % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ Check if arduino is not connected ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
                if(check_if_arduino_connected == 0)
                    app.StatusEditField.Value = 'Connect the arduino hardware and press the "Connect the Arduino" button.';
                    app.WarningEditField.Value = ''; % Clearing the Warning messsage box
                    beep;
                    clear all % To clear the previous arduino object
                    return
                end
                %
                % Check if kicking is already going on
                if(check_if_kicking_on == 1)
                    app.StatusEditField.Value = 'Kicking is going on. Wait until it finishes...';
                    beep;
                    return % The function returns to the main terminal
                end
                %
                % If the arduino board is connected and no kicking is going on, the following commands will be executed
                %
                %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx
                %
                % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ Calculation of parameters by reading the user input ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
                %
                tot_disp1 = app.WallDisplacementmmEditField.Value + gap_between_act_wall; % total displacement for actuator1
                tot_disp2 = app.WallDisplacementmmEditField_2.Value + gap_between_act_wall; % total displacement for actuator2
                pos1 = tot_disp1/m_act1; % position variable for actuator1
                pos2 = tot_disp2/m_act2; % position variable for actuator2
                delay_time1 = gap_between_act_wall/no_load_speed + app.WallDisplacementmmEditField.Value/full_load_speed...
                    + app.PauseatthepicksEditField.Value; % delay time for actuator1 in sec
                delay_time2 = gap_between_act_wall/no_load_speed + app.WallDisplacementmmEditField_2.Value/full_load_speed...
                    + app.PauseatthepicksEditField_2.Value; % delay time for actuator2 in sec
                additional_delay1 = app.DelayBetweenKickssEditField.Value - 2*gap_between_act_wall/no_load_speed; % delay between kicks
                additional_delay2 = app.DelayBetweenKickssEditField_2.Value - 2*gap_between_act_wall/no_load_speed;
                %
                % Following variables keeps the count of kicking. These are initialized with total no. of kicks desired
                kick_count_act1 = app.NoofKicksEditField_2.Value; % No. of kicks for actuator1
                kick_count_act2 = app.NoofKicksEditField_3.Value; % No. of kicks for actuator2
                %
                % Initialization of the kick duration and the Status and Warning message boxes
                app.Actuator1sEditField.Value = 0;
                app.Actuator2sEditField.Value = 0;
                app.StatusEditField.Value = '';
                app.WarningEditField.Value = '';
                %
                % Check for the position variable
                if (pos1 > 1 || pos2 > 1)
                    app.WarningEditField.Value = 'Maximum allowed wall displacement: actuator1 = 36 mm, actuator2 = 38 mm.';
                    beep;
                    return % This will end the executin of this function
                end
                %
                % Updating the status check variable for the kicking
                check_if_kicking_on = 1; % It indicates some kicking is going on. This variable will be cleared at the end of kicking
                %
                %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ Defining a timer for force data acquisition ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
                %****************************************************************************************************************************************
                %
                ti = timer; % Define a timer object
                ti.TimerFcn =@my_timer_func; % timer function for data acquisition
                ti.ExecutionMode = 'fixedRate';
                ti.period = 1/sampling_frequency; % Setting a frequency of 20 Hz
                data_number = 1; % initialing the variable to keep track of the data recording
                %
                % For realtime plotting
                % Defining the settings for the real time plot
                plot(app.UIAxes,0,0); % Refreshing the plot to remove the previous data
                h1 = animatedline (app.UIAxes, 'Color', 'b', 'DisplayName', 'Actuator1');
                h2 = animatedline (app.UIAxes, 'Color', 'm', 'DisplayName', 'Actuator2');
                legend ([h1 h2],'Location','southwest', 'Box','off');
                %
                flushinput(s); % To flush the store data from the buffer of the serial communication
                %
                tic % Starting point of the time counting
                start(ti); % Starting of the data acquisition system
                %
                % XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX
                %
                %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ Actuating the kicks ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
                %****************************************************************************************************************************************
                %
                app.StatusEditField.Value = 'Kicking is going on...';
                app.StartKickingButton.Enable = 0; % Disabling the button
                % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ Kick Mode = Single ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
                %
                if (app.KickModeDropDown.Value == '            Single')
                    if app.StartwithActuatorDropDown.Value == '1' % Actuator1 will be fired
                        %
                        if(additional_delay1 < 0 && app.DelayBetweenKickssEditField.Value ~= 0)
                            % In case the dely between kicks is not the default value (= 0) or the calculated additinal delay is < 0
                            % the following warning will be diplayed
                            app.WarningEditField.Value = '"Delay Between Kicks" is less than the minimum delay required.';
                            beep;
                        end
                        %
                        while(kick_count_act1 > 0) % Until the variable is greater than 0, the kicking will continue
                            writePosition(act1,pos1);
                            pause(delay_time1); % Allows the actuator to reach the maximum position
                            writePosition(act1,0);
                            pause(delay_time1); % Allows the actuator to return to minimum position
                            %
                            % Check for additional delay between consequitive kicks
                            if(additional_delay1 > 0)
                                pause(additional_delay1); % Additional delay between consequitive kicks
                            end
                            %
                            kick_count_act1 = kick_count_act1 - 1; % Reduction of the kick counting variable
                        end
                        %
                    else % Actuator2 will be fired
                        %
                        if(additional_delay2 < 0 && app.DelayBetweenKickssEditField_2.Value ~= 0)
                            app.WarningEditField.Value = '"Delay Between Kicks" is less than the minimum delay required.';
                            beep;
                        end
                        %
                        while(kick_count_act2 > 0) % Until the variable is greater than 0, the kicking will continue
                            writePosition(act2,pos2);
                            pause(delay_time2); % Allows the actuator to reach the maximum position
                            writePosition(act2, 0);
                            pause(delay_time2); % Allows the actuator to reach the minimum position
                            %
                            % Check for additional delay between consequitive kicks
                            if(additional_delay2 > 0)
                                pause(additional_delay2); % Additional delay between consequitive kicks
                            end
                            %
                            kick_count_act2 = kick_count_act2 - 1; % Reduction of the kick counting variable
                        end
                    end
                    %
                    % Display the kick durations
                    app.Actuator1sEditField.Value = 2 * (delay_time1 - gap_between_act_wall/no_load_speed) - app.PauseatthepicksEditField.Value;
                    app.Actuator2sEditField.Value = 2 * (delay_time2 - gap_between_act_wall/no_load_speed) - app.PauseatthepicksEditField_2.Value;
                    %
                    % XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX
                    %
                    % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ Kick Mode = Dual: Simulteneous ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
                    %
                elseif (app.KickModeDropDown.Value == 'Dual: Simulteneous')
                    %
                    if (app.StartwithActuatorDropDown.Value == '1')
                        kick_count_act = kick_count_act1;
                    else
                        kick_count_act = kick_count_act2;
                    end
                    %
                    while(kick_count_act > 0)
                        writePosition(act1,pos1);
                        writePosition(act2,pos2);
                        %
                        if (delay_time1 < delay_time2) % If the delay required for actuator1 is less than actuator2
                            pause(delay_time1); % Pause for lower delay
                            writePosition(act1,0); % Write the return position to actuator1
                            pause(delay_time2 - delay_time1); % Pause for the rest of the delay
                            writePosition(act2,0); % Write the return position to actuator2
                            pause (delay_time2); % Pause for the higher delay, so that next cycle starts together
                            %
                        else % delay_time1 > delay_time_2
                            pause(delay_time2);
                            writePosition(act2,0);
                            pause(delay_time1 - delay_time2);
                            writePosition(act1,0);
                            pause (delay_time1);
                        end
                        %
                        kick_count_act = kick_count_act - 1;
                    end
                    %
                    % Display the kick durations
                    app.Actuator1sEditField.Value = 2 * (delay_time1 - gap_between_act_wall/no_load_speed) - app.PauseatthepicksEditField.Value;
                    app.Actuator2sEditField.Value = 2 * (delay_time2 - gap_between_act_wall/no_load_speed) - app.PauseatthepicksEditField_2.Value;
                    %
                    % XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX
                    %
                    % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ Kick Mode = Dual: Consequitve ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
                    %
                elseif (app.KickModeDropDown.Value == 'Dual: Consequitive')
                    max_kick_count = max(kick_count_act1, kick_count_act2);
                    %
                    % Actuating the kicks
                    if app.StartwithActuatorDropDown.Value == '1' % Actuator 1 will be fired first
                        %
                        while (max_kick_count > 0) % loop for multiple kicks
                            %
                            if (kick_count_act1 > 0) % Individual actuator will be checked before each kicking
                                writePosition(act1,pos1);
                                pause(delay_time1); % Allows the actuator to reach the maximum position
                                writePosition(act1,0);
                                pause(delay_time1); % Allows the actuator to return to minimum position
                                %
                                kick_count_act1 = kick_count_act1 - 1; % reduce the kick counting variable
                                %
                            end
                            %
                            if (kick_count_act2 > 0)
                                writePosition(act2,pos2);
                                pause(delay_time2); % Allows the actuator to reach the maximum position
                                writePosition(act2, 0);
                                pause(delay_time2); % Allows the actuator to reach the minimum position
                                %
                                kick_count_act2 = kick_count_act2 - 1;
                            end
                            %
                            max_kick_count = max_kick_count - 1;
                        end
                        %
                    else % Actuator 2 will be fired first
                        while (max_kick_count > 0) % loop for multiple kicks
                            %
                            if (kick_count_act2 > 0)
                                writePosition(act2,pos2);
                                pause(delay_time2); % Allows the actuator to reach the maximum position
                                writePosition(act2, 0);
                                pause(delay_time2); % Allows the actuator to reach the minimum position
                                %
                                kick_count_act2 = kick_count_act2 - 1;
                            end
                            if (kick_count_act1 > 0)
                                writePosition(act1,pos1);
                                pause(delay_time1); % Allows the actuator to reach the maximum position
                                writePosition(act1,0);
                                pause(delay_time1); % Allows the actuator to return to minimum position
                                %
                                kick_count_act1 = kick_count_act1 - 1; % reduce the kick counting variable
                                %
                            end
                            %
                            max_kick_count = max_kick_count - 1;
                        end
                    end
                    %
                    % Display the kick durations
                    app.Actuator1sEditField.Value = 2 * (delay_time1 - gap_between_act_wall/no_load_speed) - app.PauseatthepicksEditField.Value;
                    app.Actuator2sEditField.Value = 2 * (delay_time2 - gap_between_act_wall/no_load_speed) - app.PauseatthepicksEditField_2.Value;
                    %
                    % XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX
                    %
                    % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ Kick Mode = Dual: Random ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
                    %
                else
                    max_kick_count = max(kick_count_act1, kick_count_act2);
                    %
                    % Actuating the kicks
                    if (app.StartwithActuatorDropDown.Value == '1') % Actuator1 will be fired first
                        %
                        while (max_kick_count > 0) % loop for multiple kicks
                            %
                            act1_kick = 0; % Variable to mark whether actuator1 is kicking within an iteration
                            %
                            if (kick_count_act1 > 0) % If actuator1 is kicking
                                writePosition(act1,pos1);
                                kick_count_act1 = kick_count_act1 - 1; % reducing the kick counting variable for actuator1
                                act1_kick = 1; % This variable indicates whether actuator1 is kicking or not, inside an iteration
                            end
                            %
                            if (kick_count_act2 > 0) % If actuator2 is kicking
                                %
                                kick_count_act2 = kick_count_act2 - 1; % reducing the kick counting variable for actuator2
                                %
                                if (act1_kick == 1) % if the actuator1 is kicking
                                    %
                                    if(app.DelayBetweenActuatorssEditField.Value < delay_time1) % If the delay between actuators is smaller, that delay will be made first
                                        pause(app.DelayBetweenActuatorssEditField.Value);
                                        writePosition(act2,pos2); % After the delay, the actuator2 will start
                                        %
                                        if ((delay_time1 - app.DelayBetweenActuatorssEditField.Value) < delay_time2)
                                            pause(delay_time1 - app.DelayBetweenActuatorssEditField.Value);
                                            writePosition(act1,0);
                                            pause(delay_time2 - (delay_time1 - app.DelayBetweenActuatorssEditField.Value));
                                            writePosition(act2,0);
                                            pause(max(delay_time2,(delay_time1-(delay_time2 - (delay_time1 - app.DelayBetweenActuatorssEditField.Value)))));
                                        else
                                            pause(delay_time2);
                                            writePosition(act2,0);
                                            pause(delay_time1 - (app.DelayBetweenActuatorssEditField.Value + delay_time2));
                                            writePosition(act1,0);
                                            pause(max(delay_time1,(delay_time2 - (delay_time1 - (app.DelayBetweenActuatorssEditField.Value + delay_time2)))));
                                        end
                                        %
                                    else % If the delay for actuator1 is smaller, that delay will be made first
                                        pause(delay_time1);
                                        writePosition(act1,0); % After the delay, the actuator1 will start to return to the origin
                                        pause(app.DelayBetweenActuatorssEditField.Value - delay_time1); % Rest of the delay before starting the actuator2
                                        writePosition(act2,pos2); % Starting the actuator2
                                        %
                                        if (delay_time1 <= (app.DelayBetweenActuatorssEditField.Value - delay_time1)) % Actuator1 has already returned to origin
                                            pause(delay_time2);
                                            writePosition(act2,0); % Actuator2 start returning
                                            pause(delay_time2);
                                        else
                                            if ((delay_time1 - (app.DelayBetweenActuatorssEditField.Value - delay_time1)) <= delay_time2)
                                                pause(delay_time2); % Actuator1 will return to origin by this time
                                                writePosition(act2,0); % Actuator2 start returning
                                                pause(delay_time2);
                                            else
                                                pause(delay_time2);
                                                writePosition(act2,0); % Actuator2 start returning
                                                pause(max(delay_time2,(delay_time1 - ((app.DelayBetweenActuatorssEditField.Value - delay_time1) + delay_time2))));
                                                % (app.DelayBetweenActuatorssEditField.Value - delay_time1) + delay_time2 = time already spent
                                                % after starting the return journey of actuator1
                                            end
                                        end
                                        %
                                    end
                                    %
                                else % Actuator1 is not kicking
                                    writePosition(act2,pos2);
                                    pause(delay_time2);
                                    writePosition(act2,0);
                                    pause(delay_time2);
                                end
                                %
                            else % If actuator2 is not kicking
                                if (act1_kick == 1) % If the actuator1 is kicking; probably, this condition is always gonna be true when actuator2 is not kicking
                                    pause(delay_time1);
                                    writePosition(act1,0);
                                    pause(delay_time1);
                                end
                            end
                            %
                            max_kick_count = max_kick_count - 1;
                            %
                        end
                        %
                    else % Actuator2 is kicking first
                        while (max_kick_count > 0) % loop for multiple kicks
                            %
                            act2_kick = 0;
                            %
                            if (kick_count_act2 > 0) % If actuator2 is kicking
                                writePosition(act2,pos2);
                                kick_count_act2 = kick_count_act2 - 1; % reducing the kick counting variable for the actuator2
                                act2_kick = 1; % This variable indicates whether actuator2 is kicking or not inside an iteration
                            end
                            %
                            if (kick_count_act1 > 0) % If actuator1 is kicking
                                %
                                kick_count_act1 = kick_count_act1 - 1; % reducing the kick counting variable for actuator1
                                %
                                if (act2_kick == 1) % if the actuator2 is kicking
                                    %
                                    if(app.DelayBetweenActuatorssEditField.Value < delay_time2) % If the delay between actuators is smaller, that delay will be made first
                                        pause(app.DelayBetweenActuatorssEditField.Value);
                                        writePosition(act1,pos1); % After the delay, the actuator1 will start
                                        %
                                        if ((delay_time2 - app.DelayBetweenActuatorssEditField.Value) < delay_time1)
                                            pause(delay_time2 - app.DelayBetweenActuatorssEditField.Value);
                                            writePosition(act2,0);
                                            pause(delay_time1 - (delay_time2 - app.DelayBetweenActuatorssEditField.Value));
                                            writePosition(act1,0);
                                            pause(max(delay_time1,(delay_time2-(delay_time1 - (delay_time2 - app.DelayBetweenActuatorssEditField.Value)))));
                                        else
                                            pause(delay_time1);
                                            writePosition(act1,0);
                                            pause(delay_time2 - (app.DelayBetweenActuatorssEditField.Value + delay_time1));
                                            writePosition(act2,0);
                                            pause(max(delay_time2,(delay_time1 - (delay_time2 - (app.DelayBetweenActuatorssEditField.Value + delay_time1)))));
                                        end
                                        %
                                    else % If the delay for actuator2 is smaller, that delay will be made first
                                        pause(delay_time2);
                                        writePosition(act2,0); % After the delay, the actuator2 will start to return to the origin
                                        pause(app.DelayBetweenActuatorssEditField.Value - delay_time2); % Rest of the delay before starting the actuator1
                                        writePosition(act1,pos1); % Starting the actuator1
                                        %
                                        if (delay_time2 <= (app.DelayBetweenActuatorssEditField.Value - delay_time2)) % Actuator2 has already returned to origin
                                            pause(delay_time1);
                                            writePosition(act1,0); % Actuator1 start returning
                                            pause(delay_time1);
                                        else % Actuator2 has not reached to origin yet
                                            if ((delay_time2 - (app.DelayBetweenActuatorssEditField.Value - delay_time2)) <= delay_time1)
                                                pause(delay_time1); % Actuator2 will return to origin before the end of this time
                                                writePosition(act1,0); % Actuator1 start returning
                                                pause(delay_time1);
                                            else
                                                pause(delay_time1); % Actuator2 will not return to origin by the end of this time
                                                writePosition(act1,0); % Actuator1 start returning
                                                pause(max(delay_time1,(delay_time2 - ((app.DelayBetweenActuatorssEditField.Value - delay_time2) + delay_time1))));
                                                % ((app.DelayBetweenActuatorssEditField.Value - delay_time2) + delay_time1) = time already spent after
                                                % starting the return journey of actuator2
                                            end
                                        end
                                        %
                                    end
                                    %
                                else % Actuator2 is not kicking
                                    writePosition(act1,pos1);
                                    pause(delay_time1);
                                    writePosition(act1,0);
                                    pause(delay_time1);
                                end
                                %
                            else % If actuator1 is not kicking
                                if (act2_kick == 1) % If the actuator2 is kicking;
                                    % probably, this condition is always gonna be true when actuator1 is not kicking
                                    pause(delay_time2);
                                    writePosition(act2,0);
                                    pause(delay_time2);
                                end
                            end
                            %
                            max_kick_count = max_kick_count - 1;
                            %
                        end
                    end
                    %
                    % Display the kick durations
                    app.Actuator1sEditField.Value = 2 * (delay_time1 - gap_between_act_wall/no_load_speed) - app.PauseatthepicksEditField.Value;
                    app.Actuator2sEditField.Value = 2 * (delay_time2 - gap_between_act_wall/no_load_speed) - app.PauseatthepicksEditField_2.Value;
                    %
                end
                %
                % XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX
                %
                % Change of status at the end of kicking
                app.StatusEditField.Value = 'Kicking has finished.';
                app.StartKickingButton.Enable = 1; % Enabling the button
                beep;
                %
                % Updating the status check variable for the kicking
                check_if_kicking_on = 0; % Required for the next button press
                %
                % Stopping the timer
                %
                stop (ti);
                Total_Sampling_period = toc;
                app.DataSamplingPeriodsEditField.Value = Total_Sampling_period; % Upadting the total sampling period field
                %
                %                 pause (2);
                % Plotting the force vs time graph
                timeAxis1 = (linspace(0,Total_Sampling_period,length(sensor1)))'; % length(sensor1) gives the no. of element
                timeAxis2 = (linspace(0,Total_Sampling_period,length(sensor2)))';
                plot(app.UIAxes,timeAxis1,sensor1(:,1),'b',timeAxis2,sensor2(:,1),'m');
                legend(app.UIAxes,{'Actuator1','Actuator2'});
                %
                % Saving the sensor data
                sensor_data = [sensor1 timeAxis1 sensor2 timeAxis2];
                
                try
                    file_list = ls('Sensor_Data');                    
                    file_Name = file_list (end, end-6:end-4);
                catch
                    fopen('Sensor_Data\SensorData_000.txt','w');
                    fclose('all');
                    file_list = ls('Sensor_Data');
                    file_Name = file_list (end, end-6:end-4);
                end
                file_Name = str2double(file_Name)+1;
                file_Name = sprintf('SensorData_%03d.txt', file_Name);
                file_Name = strcat('C:\Users\akg18\Box Sync\My Documents\Academic- PhD_Imperial\Research Work\My Work\Test_Bed\Codes\Kick_Simulator\Sensor_Data\',file_Name);
                save (file_Name,'sensor_data', '-ascii');
                %
            catch
                app.StatusEditField.Value = 'Connection is lost. Try to reconnect';
                app.WarningEditField.Value = '';
                app.StartKickingButton.Enable = 1; % Enabling the button
                %
                % clear all;
            end
            %
            function my_timer_func(~,~)
                %
                % Reading the analog voltages from the arduino pins
                %                 sensor1(data_number)= readVoltage(a2,'A1');
                %                 sensor2(data_number) = readVoltage(a2,'A2');
                r = fgets(s);
                rn = str2num(r);
                sensor1 (data_number,1)= rn(1);
                sensor2 (data_number,1)= rn(2);
                %
                % Real-time Plotting
                if (app.RealtimePlottingCheckBox.Value == 1)
                    addpoints(h1,toc,sensor1(data_number,1));
                    addpoints(h2,toc,sensor2(data_number,1));
                    drawnow limitrate;
                    %
                end
                %
                data_number = data_number+1;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            clear all; % This important, because it removes the arduino object at the of the code
        end

        % Button pushed function: ConnecttheArduinoButton
        function ConnecttheArduinoButtonPushed(app, event)
            % initializing the message box
            app.StatusEditField.Value = 'Trying to Set up the Arduino connection...';
            app.WarningEditField.Value = '';
            pause(1); % So that you can see the above message
            %
            % Decleration of the golbal variables
            global a1 s act1 act2 gap_between_act_wall no_load_speed full_load_speed m_act1 m_act2 check_if_kicking_on check_if_arduino_connected ...
                sampling_frequency
            %
            % Difining the constants
            gap_between_act_wall = 9; % mm
            no_load_speed = 11.72; % mm/s
            full_load_speed = 7.24; % mm/s
            m_act1 = 45.475; % Tangent of the displacement vs position variable curve for actutor 1
            m_act2 = 47.091; % Tangent of the displacement vs position variable curve for actutor 1
            sampling_frequency = 20; % Hz
            %
            % Declaring some checking variables
            check_if_kicking_on = 0;
            check_if_arduino_connected = 0;
            %
            try
                % Clearing up previous serial connections
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
                %
                s = serial('COM4','BaudRate',115200); % For serial communication
                fopen(s); % Opening the serial port
                fgets(s); % Frist data is not used
            catch
                app.StatusEditField.Value = 'The serial port is not connected.';
                return
            end
            % Setting up the connection
            try
                a1 = arduino('COM10','Mega2560'); % Create an arduino object.
                %                 a2 = arduino('COM4','UNO'); % Needed when data will be directly read from the pin, not through the serial port
                % In case of an already existing connection or no connection, the program will go to catch
                %
                % Connect to the actuators in digital pin 9 and 10. Specs for max and min pulse duration
                % is taken from the datasheet of the actuator
                act1 = servo(a1, 'D3','MinPulseDuration', 1e-3, 'MaxPulseDuration', 2e-3);
                act2 = servo(a1, 'D2','MinPulseDuration', 1e-3, 'MaxPulseDuration', 2e-3);
                %
                % Setting 0 as the initial position
                writePosition(act1, 0);
                writePosition(act2, 0);
                %
                % Updating the status of the connection
                check_if_arduino_connected = 1;
                %
                % Updating the message box
                app.StatusEditField.Value = 'Arduino board is connected successfully.';
                %
            catch % In case of error occures while setting up the connection, the following commands will be executed
                slist = seriallist;
                prt = 'COM10';
                if (intersect (prt, slist) == 'COM10')
                    app.StatusEditField.Value = 'Connection already exists.';
                    check_if_arduino_connected = 1; % Updating the arduino connection checking variable
                else
                    app.StatusEditField.Value = 'Arduino Connection is not found. Check the USB cable and try again.';
                    clear all; % This line does not clear the base workspace
                    evalin('base','clear all'); % to clear the variables in the base workspace
                    return
                end
            end
            %
            clearvars -except a1 a2 act1 act2 gap_between_act_wall no_load_speed full_load_speed m_act1 m_act2 check_if_kicking_on...
                check_if_arduino_connected sensor1 sensor2 data_number
            % All the other variables except the above mentioned will be cleared. This is necessary as we couldn't use the clear all at the beginning.
        end

        % Value changed function: KickModeDropDown
        function KickModeDropDownValueChanged(app, event)
            Kick_mode_value = app.KickModeDropDown.Value;
            %
            if (Kick_mode_value == '      Dual: Random')
                %
                app.DelayBetweenActuatorssEditField.Editable = 1;
                app.DelayBetweenActuatorssEditField.Value = 0; % Initializes the value
                %
                % Fields for Actuator 1
                app.NoofKicksEditField_2.Editable = 1;
                app.WallDisplacementmmEditField.Editable = 1;
                app.PauseatthepicksEditField.Editable = 1;
                app.DelayBetweenKickssEditField.Editable = 0; % "Delay Between Kicks" is not available in this mode
                app.DelayBetweenKickssEditField.Value = 0; % Changed back to default value
                %
                % Fields for Actuator 2
                app.NoofKicksEditField_3.Editable = 1;
                app.WallDisplacementmmEditField_2.Editable = 1;
                app.PauseatthepicksEditField_2.Editable = 1;
                app.DelayBetweenKickssEditField_2.Editable = 0; % "Delay Between Kicks" is not available in this mode
                app.DelayBetweenKickssEditField_2.Value = 0;
                %
                app.WarningEditField.Value = '"Delay Between Kicks" is not available in this mode.';
                %
            elseif (Kick_mode_value == '            Single')
                %
                app.DelayBetweenActuatorssEditField.Editable = 0; % "Delay between actuators" is not available in this mode
                app.DelayBetweenActuatorssEditField.Value = 0; % Changed back to default value
                %
                % Fields for Actuator 1
                app.NoofKicksEditField_2.Editable = 1;
                app.WallDisplacementmmEditField.Editable = 1;
                app.PauseatthepicksEditField.Editable = 1;
                app.DelayBetweenKickssEditField.Editable = 1; % "Delay Between Kicks" is available in this mode
                %
                % Fields for Actuator 2
                app.NoofKicksEditField_3.Editable = 1;
                app.WallDisplacementmmEditField_2.Editable = 1;
                app.PauseatthepicksEditField_2.Editable = 1;
                app.DelayBetweenKickssEditField_2.Editable = 1;
                %
                app.WarningEditField.Value = '"Delay Between Actuators" is not available in this mode.';
                %
            else % In 'Dual: Simulteneous' and 'Dual: Consequitive' mode
                app.DelayBetweenActuatorssEditField.Editable = 0; % "Delay between actuators" is not available in this mode
                app.DelayBetweenActuatorssEditField.Value = 0; % Changed back to default value
                %
                % Fields for Actuator 1
                app.NoofKicksEditField_2.Editable = 1;
                app.WallDisplacementmmEditField.Editable = 1;
                app.PauseatthepicksEditField.Editable = 1;
                app.DelayBetweenKickssEditField.Editable = 0; % "Delay Between Kicks" is not available in this mode
                app.DelayBetweenKickssEditField.Value = 0; % Changed back to default value
                %
                % Fields for Actuator 2
                app.NoofKicksEditField_3.Editable = 1;
                app.WallDisplacementmmEditField_2.Editable = 1;
                app.PauseatthepicksEditField_2.Editable = 1;
                app.DelayBetweenKickssEditField_2.Editable = 0; % "Delay Between Kicks" is not available in this mode
                app.DelayBetweenKickssEditField_2.Value = 0; % Changed back to default value
                %
                if ((Kick_mode_value == 'Dual: Simulteneous') & (app.StartwithActuatorDropDown.Value == '1')) % Additional condition in case the mode is 'Dual: Simulteneous'
                    app.NoofKicksEditField_3.Editable = 0; % No. of kicks will be taken from the Actuator1
                    app.NoofKicksEditField_3.Value = 0;
                    %
                elseif ((Kick_mode_value == 'Dual: Simulteneous') & (app.StartwithActuatorDropDown.Value == '1'))
                    app.NoofKicksEditField_2.Editable = 0; % No. of kicks will be taken from the Actuator2
                    app.NoofKicksEditField_2.Value = 0;
                end
                %
                app.WarningEditField.Value = '"Delay Between Actuators" & "Delay Between Kicks" are not available in this mode';
                %
            end
            %
        end

        % Value changed function: StartwithActuatorDropDown
        function StartwithActuatorDropDownValueChanged(app, event)
            start_with_actuator_value = app.StartwithActuatorDropDown.Value;
            %
            if ((start_with_actuator_value == '1') & (app.KickModeDropDown.Value == '            Single'))
                % Turn on the Actuator 1
                app.NoofKicksEditField_2.Editable = 1;
                app.WallDisplacementmmEditField.Editable = 1;
                app.PauseatthepicksEditField.Editable = 1;
                app.DelayBetweenKickssEditField.Editable = 1;
                %
                % Turn off the Actuator 2
                app.NoofKicksEditField_3.Editable = 0;
                app.NoofKicksEditField_3.Value = 0; % Resetting the No. of Kicks to 0 so that Actuator 2 is disabled
                app.WallDisplacementmmEditField_2.Editable = 0;
                app.PauseatthepicksEditField_2.Editable = 0;
                app.DelayBetweenKickssEditField_2.Editable = 0;
                %
            elseif ((start_with_actuator_value == '2') & (app.KickModeDropDown.Value == '            Single'))
                % Turn off the Actuator 1
                app.NoofKicksEditField_2.Editable = 0;
                app.NoofKicksEditField_2.Value = 0; % Resetting the No. of Kicks to 0 so that Actuator 1 is disabled
                app.WallDisplacementmmEditField.Editable = 0;
                app.PauseatthepicksEditField.Editable = 0;
                app.DelayBetweenKickssEditField.Editable = 0;
                %
                % Turn on the Actuator 2
                app.NoofKicksEditField_3.Editable = 1;
                app.WallDisplacementmmEditField_2.Editable = 1;
                app.PauseatthepicksEditField_2.Editable = 1;
                app.DelayBetweenKickssEditField_2.Editable = 1;
                %
            elseif ((start_with_actuator_value == '1') & (app.KickModeDropDown.Value == 'Dual: Simulteneous'))
                app.NoofKicksEditField_2.Editable = 1; % No. of kick in Actuator1 becomes editable
                app.NoofKicksEditField_3.Editable = 0;
                app.NoofKicksEditField_3.Value = 0;
                %
            elseif ((start_with_actuator_value == '2') & (app.KickModeDropDown.Value == 'Dual: Simulteneous'))
                app.NoofKicksEditField_2.Editable = 0;
                app.NoofKicksEditField_2.Value = 0;
                app.NoofKicksEditField_3.Editable = 1; % No. of kick in Actuator2 becomes editable
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.KickDesignLabel = uilabel(app.UIFigure);
            app.KickDesignLabel.Text = 'Kick Design';
            app.Actuator1Label = uilabel(app.UIFigure);
            app.Actuator1Label.Text = 'Actuator 1';
            app.Actuator2Label = uilabel(app.UIFigure);
            app.Actuator2Label.Text = 'Actuator 2';
            app.WallDisplacementmmLabel = uilabel(app.UIFigure);
            app.WallDisplacementmmLabel.Text = 'Wall Displacement (mm)';
            app.WallDisplacementmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Actuator1sEditFieldLabel = uilabel(app.UIFigure);
            app.Actuator1sEditFieldLabel.Text = 'Actuator 1 (s)';
            app.Actuator1sEditField = uieditfield(app.UIFigure, 'numeric');
            app.Actuator1sEditField.Editable = 'off';
            app.PauseatthepicksLabel = uilabel(app.UIFigure);
            app.PauseatthepicksLabel.Text = 'Pause at the pick (s)';
            app.PauseatthepicksEditField = uieditfield(app.UIFigure, 'numeric');
            app.WallDisplacementmmLabel_2 = uilabel(app.UIFigure);
            app.WallDisplacementmmLabel_2.Text = 'Wall Displacement (mm)';
            app.WallDisplacementmmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.WallDisplacementmmEditField_2.Editable = 'off';
            app.Actuator2sEditFieldLabel = uilabel(app.UIFigure);
            app.Actuator2sEditFieldLabel.Text = 'Actuator 2 (s)';
            app.Actuator2sEditField = uieditfield(app.UIFigure, 'numeric');
            app.Actuator2sEditField.Editable = 'off';
            app.PauseatthepicksLabel_2 = uilabel(app.UIFigure);
            app.PauseatthepicksLabel_2.Text = 'Pause at the pick (s)';
            app.PauseatthepicksEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PauseatthepicksEditField_2.Editable = 'off';
            app.StartwithActuatorLabel = uilabel(app.UIFigure);
            app.StartwithActuatorLabel.Text = 'Start with: Actuator';
            app.StartwithActuatorDropDown = uidropdown(app.UIFigure);
            app.StartwithActuatorDropDown.Items = {'1', '2'};
            app.StartwithActuatorDropDown.ValueChangedFcn = createCallbackFcn(app, @StartwithActuatorDropDownValueChanged, true);
            app.StartwithActuatorDropDown.Value = '1';
            app.NoofKicksEditField_2Label = uilabel(app.UIFigure);
            app.NoofKicksEditField_2Label.Text = 'No. of Kicks';
            app.NoofKicksEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NoofKicksEditField_3Label = uilabel(app.UIFigure);
            app.NoofKicksEditField_3Label.Text = 'No. of Kicks';
            app.NoofKicksEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.NoofKicksEditField_3.Editable = 'off';
            app.DelayBetweenKickssLabel = uilabel(app.UIFigure);
            app.DelayBetweenKickssLabel.Text = 'Delay Between Kicks (s)';
            app.DelayBetweenKickssEditField = uieditfield(app.UIFigure, 'numeric');
            app.FetalKickSimulatorLabel = uilabel(app.UIFigure);
            app.FetalKickSimulatorLabel.Text = 'Fetal Kick Simulator';
            app.KickingActionLabel = uilabel(app.UIFigure);
            app.KickingActionLabel.Text = 'Kicking Action';
            app.StartKickingButton = uibutton(app.UIFigure, 'push');
            app.StartKickingButton.ButtonPushedFcn = createCallbackFcn(app, @StartKickingButtonPushed, true);
            app.StartKickingButton.Text = 'Start Kicking';
            app.DelayBetweenActuatorssLabel = uilabel(app.UIFigure);
            app.DelayBetweenActuatorssLabel.Text = {'Delay Between '; 'Actuators (s)'};
            app.DelayBetweenActuatorssEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelayBetweenActuatorssEditField.Editable = 'off';
            app.FeedbackLabel = uilabel(app.UIFigure);
            app.FeedbackLabel.Text = 'Feedback';
            app.DelayBetweenKickssLabel_2 = uilabel(app.UIFigure);
            app.DelayBetweenKickssLabel_2.Text = 'Delay Between Kicks (s)';
            app.DelayBetweenKickssEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DelayBetweenKickssEditField_2.Editable = 'off';
            app.KickDurationLabel = uilabel(app.UIFigure);
            app.KickDurationLabel.Text = 'Kick Duration';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            app.KickModeDropDownLabel = uilabel(app.UIFigure);
            app.KickModeDropDownLabel.Text = 'Kick Mode';
            app.KickModeDropDown = uidropdown(app.UIFigure);
            app.KickModeDropDown.Items = {'            Single', 'Dual: Simulteneous', 'Dual: Consequitive', '      Dual: Random'};
            app.KickModeDropDown.ValueChangedFcn = createCallbackFcn(app, @KickModeDropDownValueChanged, true);
            app.KickModeDropDown.Value = '            Single';
            app.WarningEditFieldLabel = uilabel(app.UIFigure);
            app.WarningEditFieldLabel.Text = 'Warning';
            app.WarningEditField = uieditfield(app.UIFigure, 'text');
            app.WarningEditField.Editable = 'off';
            app.ConnecttheArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttheArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttheArduinoButtonPushed, true);
            app.ConnecttheArduinoButton.Text = 'Connect the Arduino';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Force applied by the actuators')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Force (N)')
            app.DataSamplingPeriodsEditFieldLabel = uilabel(app.UIFigure);
            app.DataSamplingPeriodsEditFieldLabel.Text = 'Data Sampling Period (s)';
            app.DataSamplingPeriodsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DataSamplingPeriodsEditField.Editable = 'off';
            app.RealtimePlottingCheckBox = uicheckbox(app.UIFigure);
            app.RealtimePlottingCheckBox.Text = 'Realtime Plotting';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Kick_Simulator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end